classdef PRMIA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PortfolioSelectionTab           matlab.ui.container.Tab
        AddOptiontoPortfolioPanel       matlab.ui.container.Panel
        SetDateandRatesButton           matlab.ui.control.Button
        AddtoPortfolioButton            matlab.ui.control.Button
        DeletePortfolioButton           matlab.ui.control.Button
        OptionStyleButtonGroup          matlab.ui.container.ButtonGroup
        EuropeanButton                  matlab.ui.control.RadioButton
        AmericanButton                  matlab.ui.control.RadioButton
        PositionButtonGroup             matlab.ui.container.ButtonGroup
        LongButton                      matlab.ui.control.RadioButton
        ShortButton                     matlab.ui.control.RadioButton
        OptionTypeButtonGroup           matlab.ui.container.ButtonGroup
        CallButton                      matlab.ui.control.RadioButton
        PutButton                       matlab.ui.control.RadioButton
        InstrumentTypeDropDown          matlab.ui.control.DropDown
        InstrumentTypeDropDownLabel     matlab.ui.control.Label
        StrikeEditField                 matlab.ui.control.NumericEditField
        StrikeEditFieldLabel            matlab.ui.control.Label
        UnderlyingPriceEditFieldLabel   matlab.ui.control.Label
        UnderlyingPriceEditField        matlab.ui.control.NumericEditField
        VolatilityEditFieldLabel        matlab.ui.control.Label
        VolatilityEditField             matlab.ui.control.NumericEditField
        OptionMaturityDatePickerLabel   matlab.ui.control.Label
        OptionMaturityDatePicker        matlab.ui.control.DatePicker
        YieldEditField                  matlab.ui.control.NumericEditField
        YieldLabel                      matlab.ui.control.Label
        BarrierPanel                    matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        BarrierTypeDropDown             matlab.ui.control.DropDown
        BarrierTypeDropDownLabel        matlab.ui.control.Label
        BarrierValueEditFieldLabel      matlab.ui.control.Label
        BarrierValueEditField           matlab.ui.control.NumericEditField
        BinaryPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        BinaryValueEditField            matlab.ui.control.NumericEditField
        BinaryValueEditFieldLabel       matlab.ui.control.Label
        SimulationTrialsEditFieldLabel  matlab.ui.control.Label
        SimulationTrialsEditField       matlab.ui.control.NumericEditField
        QuantitySpinner                 matlab.ui.control.Spinner
        QuantitySpinnerLabel            matlab.ui.control.Label
        BaseCurrencyDropDownLabel       matlab.ui.control.Label
        BaseCurrencyDropDown            matlab.ui.control.DropDown
        PortfolioCompositionPanel       matlab.ui.container.Panel
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UITable3                        matlab.ui.control.Table
        UITable4                        matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        LONGLabel                       matlab.ui.control.Label
        SHORTLabel                      matlab.ui.control.Label
        DataandPortfolioTab             matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        UIAxes6                         matlab.ui.control.UIAxes
        UITable5                        matlab.ui.control.Table
        Panel_2                         matlab.ui.container.Panel
        CheckBox                        matlab.ui.control.CheckBox
        CheckBox2                       matlab.ui.control.CheckBox
        CheckBox3                       matlab.ui.control.CheckBox
        CheckBox4                       matlab.ui.control.CheckBox
        CheckBox5                       matlab.ui.control.CheckBox
        CheckBox6                       matlab.ui.control.CheckBox
        CheckBox7                       matlab.ui.control.CheckBox
        StartDateDatePickerLabel        matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        EndDateDatePicker               matlab.ui.control.DatePicker
        Tab2                            matlab.ui.container.Tab
    end

    
%     Dichiarazione Properties
    
    properties (Access = public)
        RateDialog % Rate and Settle Panel 
        NoRatesSetERROR % sconosciuto
        CurrencySetError % Error when setting option base currency with empty rates data
    end
    
    properties (Access = public)
        Q  % Q strumenti in AddtoPortfolio Callback
        Instr % Strumenti
        counter = 0 % contatore push AddtoPortfolio
        counter2 = 0 %Conta il numero di eliminazioni di strumenti uguali
        Pricer %Pricer
        OptionTypeCategorical
        OptionTypeCategoricalLong
        OptionTypeCategoricalShort % Crea un array di strumenti
        ShortInstrumentCounter = 0
        LongInstrumentCounter = 0
        TestInstr 
        Settle % Variabile temporanea per salvare la data dalla finestra dialogo
        ZeroCurveEUR
        ZeroCurveUSD
        ZeroCurveAUD
        ZeroCurveGBP
        Dates 
        Rates
        Currency
       
    end
    
    properties (Access = private)
        
    end


%     Dichiarazione Funzioni


    methods (Access = public)
       
    end
    
    methods (Access = private)
        
        function [PortPrice, InstPrice, PortSens, InstSens] = PortCreationPricing(app,Instruments,Q,Pricer)


            myport = finportfolio(Instruments,Pricer,Q);

            [PortPrice, InstPrice, PortSens, InstSens] = pricePortfolio(myport);

            
        end
        
        
        function ReSetHomePlots(app)
            app.UITable2.Data = [];
            app.UITable3.Data = [];
            app.UITable4.Data = [];
            p = pie(app.UIAxes2,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            p = pie(app.UIAxes3,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            p = pie(app.UIAxes4,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            p = pie(app.UIAxes5,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            colormap(app.UIAxes2,parula(0));
            colormap(app.UIAxes3,parula(0));
            colormap(app.UIAxes4,parula(0));
            colormap(app.UIAxes5,parula(0));
            
        end
        
        
        function UpdateHomePlots(app,InstPrice,InstSens,PortSens)
            
%     Creazione Font
            fontstyle = uistyle;
            fontstyle.FontWeight = 'bold';
            fontstyleAl = uistyle;
            fontstyleAl.HorizontalAlignment = 'center';
            
%     Dati pos long     

            app.UITable2.Data = InstSens(InstSens.Price > 0,:);
            HeightTable = height(app.UITable2.Data);
            c2 = parula(HeightTable);
            for i = 1:HeightTable
                s = uistyle("BackgroundColor",c2(i,:));
                s.FontColor = c2(i,:);              
                addStyle(app.UITable2,s,'cell',[i 17])  %modificare numero della colonna quando si aggiungeranno le altre
            end
            addStyle(app.UITable2,fontstyle,'column',7:16)
            addStyle(app.UITable2,fontstyleAl,'column', 7:16)
            
            app.UITable2.ColumnName = InstSens.Properties.VariableNames;
            
            %Dati pos short
            app.UITable3.ColumnName = InstSens.Properties.VariableNames;
            app.UITable3.Data = InstSens(InstPrice < 0,:);
              HeightTable = height(app.UITable3.Data);
            c3 = parula(HeightTable);
            for i = 1:HeightTable
                s = uistyle("BackgroundColor",c3(i,:));
                s.FontColor = c3(i,:);              
                addStyle(app.UITable3,s,'cell',[i 17])  %modificare numero della colonna quando si aggiungeranno le altre
            end
            addStyle(app.UITable3,fontstyle,'column',7:16)
            addStyle(app.UITable3,fontstyleAl,'column', 7:16)
            
            %Dati globali Portafoglio
            app.UITable4.ColumnName = PortSens.Properties.VariableNames;
            app.UITable4.RowName = PortSens.Properties.RowNames;
            app.UITable4.Data = PortSens(:,[1:3 5:7]);
            
            
            TempPieShortData = -InstPrice(InstPrice <0);
            TempPieLongData = InstPrice(InstPrice > 0);
            
            %%%%%%%%%%%%%%%%%%%%%%%% Controllare
            if isempty(TempPieLongData) == 1
                TempPieLongData = categorical("No Instruments");
            end
            
            if isempty(TempPieShortData) == 1
                TempPieShortData = categorical("No Instruments");
            end
           
%       Plot Torte          
            PieLongValue = pie(app.UIAxes2,TempPieLongData);           
            colormap(app.UIAxes2,c2)
            
            PieShortValue = pie(app.UIAxes3,TempPieShortData);
            colormap(app.UIAxes3,c3)
            
            NumSegLong = length(PieLongValue);
            NumSegShort = length(PieShortValue);
            
            for i = 1:2:NumSegLong 
                PieLongValue(i).EdgeColor = 'w';
            end
            
            for k = 2:2:NumSegLong
                PieLongValue(k).Color = 'w';
            end
            
             for i = 1:2:NumSegShort 
                PieShortValue(i).EdgeColor = 'w';
            end
            
            for k = 2:2:NumSegShort
                PieShortValue(k).Color = 'w';
            end


%    If per creare i categorical per le torte. La torta riporta il
%    totale della categoria in rapporto al totale delle opzioni,
%    non al prezzo


%       If per evitare errori per mancanza di dati nei pie chart
            
           if app.LongInstrumentCounter > 0
              PieCatLong = pie(app.UIAxes4,categorical(app.OptionTypeCategoricalLong));
              colormap(app.UIAxes4,"autumn")
              NumSegLong = length(PieCatLong);
                for i = 1:2:NumSegLong 
                    PieCatLong(i).EdgeColor = 'w';
                end
            
                for k = 2:2:NumSegLong
                    PieCatLong(k).Color = 'w';
                end
              
           end


            if app.ShortInstrumentCounter > 0
                 PieCatShort = pie(app.UIAxes5,categorical(app.OptionTypeCategoricalShort));
                 colormap(app.UIAxes5,"autumn")                 
                 NumSegShort = length(PieCatShort); 
                for i = 1:2:NumSegShort 
                    PieCatShort(i).EdgeColor = 'w';
                end
            
                for k = 2:2:NumSegShort
                    PieCatShort(k).Color = 'w';
                end
           
            end


        end


        function data = TimeSeriesDataImport(app)
            url = 'https://fred.stlouisfed.org/';
            c = fred(url);
            
            c.DataReturnFormat = 'table';
            c.DatetimeType = 'datetime';


% Fetch all available daily foreign exchange rates between the US dollar and the Euro using the series 'DEXUSEU'. The table d contains one row for the series. Each variable describes a piece of information about the series.

            series = {'DEXUSEU' 'DEXJPUS'};
            startdate = app.StartDateDatePicker.Value;
            enddate = app.EndDateDatePicker.Value;
            d = fetch(c,series,startdate,enddate);
            
            for i = 1:length(series)
                data = d.Data;
            end
            close(c)

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Disabilita i campi inserimento non per la plain vanilla
            app.BarrierValueEditField.Enable = 'off';
            app.BinaryValueEditField.Enable = 'off';
            app.BarrierTypeDropDown.Enable = 'off';
            app.OptionMaturityDatePicker.Limits = [datetime('01-01-1999',"Format","dd-MM-uuuu") datetime('01-01-2100',"Format","dd-MM-uuuu")];
            app.EndDateDatePicker.Value = datetime("today");
           
           
            %Grafici a torta inizializzazione
            ReSetHomePlots(app)
            
            
            %imposta formato decimali
            format bank;
          
            %Plot dati cambio su grafico
            fxdata = TimeSeriesDataImport(app);
            app.UITable5.Data = fxdata{1};
            Dates = fxdata{1}.Var1;
            FX = fxdata{1}.Var2;
            plot(app.UIAxes6,Dates,FX)  
            app.UITable5.ColumnName = {'Date' 'Exchange Rate'}


        end

        % Button pushed function: AddtoPortfolioButton
        function AddtoPortfolioButtonPushed(app, event)
             app.counter = app.counter +1;
             d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Adding Option to Portfolio'); 
                       
             
%             Le Variabili ricevono i dati dagli elementi interattivi della
%             %dashboard
            OptionType = app.InstrumentTypeDropDown.Value;
            Strike = app.StrikeEditField.Value;
            LongShort = app.PositionButtonGroup.SelectedObject.Text;
            CallPut = app.OptionTypeButtonGroup.SelectedObject.Text;
            BinaryValue = app.BinaryValueEditField.Value;
            BarrierValue = app.BarrierValueEditField.Value;
            BarrierType = app.BarrierTypeDropDown.Value;
            MaturityDate = app.OptionMaturityDatePicker.Value;
            OptionStyle = app.OptionStyleButtonGroup.SelectedObject.Text;
            class(OptionStyle)


d.Value = .33; 
d.Message = 'Creating Portfolio';


%             If per cambio segno alla quantità per avere posizioni long e
%             short
            Test = 'Long';
            if strcmp(LongShort,Test) == 1
                app.Q = [app.Q app.QuantitySpinner.Value]; %riferimento a property perchè già esistente
                app.OptionTypeCategoricalLong = [app.OptionTypeCategoricalLong repmat(string(OptionType),1,app.QuantitySpinner.Value)];
                
                app.LongInstrumentCounter = app.LongInstrumentCounter +1;
            else
                app.Q = [app.Q -app.QuantitySpinner.Value];
                app.OptionTypeCategoricalShort = [app.OptionTypeCategoricalShort repmat(string(OptionType),1,app.QuantitySpinner.Value)];
                app.ShortInstrumentCounter = app.ShortInstrumentCounter+1;
                LongShort = 'Short';
            end
            
%             Stringa con nome strumento
            Name = OptionType
            app.OptionTypeCategorical = [app.OptionTypeCategorical categorical(upper(cellstr(OptionType)))]
            
%             Vari if per definire lo strumento da aggiungere
            if strcmp(OptionType,'Vanilla') == 1
            app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"Strike",Strike,"ExerciseDate",MaturityDate,'ExerciseStyle',OptionStyle, 'Name',Name)];
            
            end
            
            if strcmp(OptionType,'Binary') == 1
                app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"PayoffValue",BinaryValue,"Strike",Strike,"ExerciseDate", MaturityDate,'Name',Name)];
            end
            
            if strcmp(OptionType,'Barrier') == 1
                app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"BarrierValue",BarrierValue,"BarrierType",BarrierType,"Strike",Strike,"ExerciseDate",MaturityDate, 'Name',Name,"ExerciseStyle",OptionStyle)];
            end
            
            if strcmp(OptionType, 'Lookback') == 1
                app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"Strike",Strike,"ExerciseDate",MaturityDate, 'Name',Name,"ExerciseStyle",OptionStyle)];
            end
           
            
     d.Value = .38; 
    d.Message = 'Option Added';       


%             Costruzione Pricer, anteposta per controllo degli strumenti ed
%             eliminazione
            Settle = app.Settle;


            BLSModel = finmodel("BlackScholes","Volatility",app.VolatilityEditField.Value);
            ZeroCurveEUR = app.ZeroCurveEUR;
            ZeroCurveUSD = app.ZeroCurveUSD;
            ZeroCurveAUD = app.ZeroCurveAUD;
            ZeroCurveGBP = app.ZeroCurveGBP;
            
            
% EUR CASE            
            if app.BaseCurrencyDropDown.Value == 'EUR'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',ZeroCurveEUR,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",ZeroCurveEUR,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end

% USD CASE            
            if app.BaseCurrencyDropDown.Value == 'USD'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',ZeroCurveUSD,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",ZeroCurveUSD,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end

            
% AUD CASE            
            if app.BaseCurrencyDropDown.Value == 'AUD'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',ZeroCurveAUD,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",ZeroCurveAUD,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end
            
% GBP CASE            
            if app.BaseCurrencyDropDown.Value == 'GBP'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',ZeroCurveGBP,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",ZeroCurveGBP,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end
            
   
d.Value = .45; 
    d.Message = 'Pricing Model Created';            
            
            
           app.Currency = [app.Currency string(app.BaseCurrencyDropDown.Value)];
           
    
            app.TestInstr(1) = 0;
            if app.counter == 1
            else 
                for i = 1:app.counter-app.counter2-1
                    if (app.Instr(i) == app.Instr(app.counter-app.counter2)) & (app.Q(i)*app.Q(app.counter-app.counter2) > 0) & (app.Currency(i) == app.BaseCurrencyDropDown.Value)
                        app.TestInstr(i) = 1;
                    else
                        app.TestInstr(i) = 0;
                    end
                end
            end
            
            if nnz(app.TestInstr) > 0
                app.Currency(app.counter-app.counter2) = [];
                app.Instr(app.counter-app.counter2) = [];
                app.Pricer(app.counter-app.counter2) = [];
                app.Q(logical([app.TestInstr 0])) = app.Q(logical([app.TestInstr 0]))+ app.Q(app.counter-app.counter2);
                app.Q(app.counter-app.counter2) = [];
                app.OptionTypeCategorical(app.counter-app.counter2) = [];
                app.counter2 = app.counter2+1;
            end


if strcmp(OptionStyle,'American') == 1            
d.Value = .55; 
    d.Message = 'Simulating Underlying Paths';
else
    d.Value = .55; 
    d.Message = 'Black-Scholes Pricing';
end            
            
            Qty = app.Q;
            Instrum = app.Instr;
            
%             
%             Costruzione Pricer
%             

            
            [PortPrice, InstPrice, PortSens, InstSens] = PortCreationPricing(app,Instrum,Qty,app.Pricer);


if strcmp(OptionStyle,'American') == 1            
d.Value = .75; 
    d.Message = 'Simulation Completed';
else
    d.Value = .75; 
    d.Message = 'Option Correctly Priced';          
end            
            
%      Creazione Variabili info strumenti       
            for i = 1: length(app.Instr)
                OptionTypetoTABLE(i,1) = categorical(upper(app.Instr(i).OptionType));
                StriketoTABLE(i,1) = app.Instr(i).Strike;
                StyletoTABLE(i,1) = app.Instr(i).ExerciseStyle;
                DatetoTABLE(i,1) = app.Instr(i).ExerciseDate;
            
                if strcmp(app.OptionTypeCategorical(1,i),'BARRIER') == 1 %Maiuscolo perchè sopra c'è upper nella creazione del vettore
                    BValuetoTABLE(i,1) = app.Instr(i).BarrierValue;
                    BTypetoTABLE(i,1) = app.Instr(i).BarrierType;
                else
                    BValuetoTABLE(i,1) = '-';
                    BTypetoTABLE(i,1) = '-';
                
                end
                
                if strcmp(app.OptionTypeCategorical(1,i),'BINARY') == 1
                    BinVatoTABLE(i,1) = app.Instr(i).BinaryValue
                else
                    BinVatoTABLE(i,1) = '-';
                end


            end
%     Creazione colonne nelle tabelle 
            InstSens.Quantity = app.Q';
            InstSens.Currency = categorical(app.Currency');
            InstSens.OptionType = app.OptionTypeCategorical';
            InstSens.Style = upper(StyletoTABLE);
            InstSens.CallPut = OptionTypetoTABLE;
            InstSens.Strike = StriketoTABLE;
            InstSens.BarrierValue = BValuetoTABLE;
            InstSens.BarrierType = BTypetoTABLE;
            InstSens.BinaryValue = BinVatoTABLE;
            InstSens.Maturity = DatetoTABLE;
            InstSens.Legend = zeros([height(InstSens(:,1)) 1]);
            InstSens.Lambda = [];
            InstSens.Properties.RowNames = {};


    d.Value = .95; 
    d.Message = 'Generating Tables and Charts';             


%       Update Plots
            
            UpdateHomePlots(app,InstPrice,InstSens,PortSens)


    d.Value = .100; 
    d.Message = 'Option Added Succesfully';            
        end

        % Value changed function: InstrumentTypeDropDown
        function InstrumentTypeDropDownValueChanged(app, event)
            value = app.InstrumentTypeDropDown.Value;
            if strcmp(value,'Barrier') == 1
                app.BarrierValueEditField.Enable = 'on';
                app.BarrierTypeDropDown.Enable = 'on';
            else
                app.BarrierValueEditField.Enable = 'off'; 
                app.BarrierTypeDropDown.Enable = 'off';
            end
            
            
            if strcmp(value,'Binary') == 1
                app.BinaryValueEditField.Enable = 'on';
                app.OptionStyleButtonGroup.Enable = 'off';
                app.EuropeanButton.Value = true;
            else
                app.BinaryValueEditField.Enable = 'off';
                app.OptionStyleButtonGroup.Enable = 'on';
            end
            
        
        end

        % Button pushed function: DeletePortfolioButton
        function DeletePortfolioButtonPushed(app, event)
            app.Instr = [];
            app.Q = [];
            app.Pricer = [];
            app.counter = 0;
            app.counter2 = 0;
            app.TestInstr = [];
            app.ShortInstrumentCounter = 0;
            app.LongInstrumentCounter = 0;
            app.OptionTypeCategoricalLong = [];
            app.OptionTypeCategoricalShort = [];
            app.OptionTypeCategorical = [];
            
%        Reset Plots and Tables
            ReSetHomePlots(app)
            
        end

        % Button pushed function: SetDateandRatesButton
        function SetDateandRatesButtonPushed(app, event)
            app.SetDateandRatesButton.Enable = 'off';
            app.RateDialog = RateandSettleDialog(app)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app.RateDialog)
            delete(app.CurrencySetError)
            delete(app)


        end

        % Value changed function: BaseCurrencyDropDown
        function BaseCurrencyDropDownValueChanged(app, event)
            value = app.BaseCurrencyDropDown.Value;
           
            if isempty(app.ZeroCurveEUR) == 1
                TEST(1) = 0;
            else
                TEST(1) = 1;
            end
            
            if isempty(app.ZeroCurveUSD) == 1
                TEST(2) = 0;
            else
                TEST(2) = 1;
            end
            
            if isempty(app.ZeroCurveAUD) == 1
                TEST(3) = 0;
            else
                TEST(3) = 1;
            end
            
            if isempty(app.ZeroCurveGBP) == 1
                TEST(4) = 0;
            else
                TEST(4) = 1;
            end
            
            CurrencyString = ["EUR" "USD" "AUD" "GBP"];
            
            
% Aggiungere errori            
            if TEST(1) == 0 & value == 'EUR'
               app.CurrencySetError = CurrencySetError(app);
               ind = find(TEST,1);
               app.BaseCurrencyDropDown.Value = CurrencyString(ind);
            end
            
            if TEST(2) == 0 & value == 'USD'
               app.CurrencySetError = CurrencySetError(app);
               ind = find(TEST,1);
               app.BaseCurrencyDropDown.Value = CurrencyString(ind);
            end
            
            
            if TEST(3) == 0 & value == 'AUD'
               app.CurrencySetError = CurrencySetError(app);
               ind = find(TEST,1);
               app.BaseCurrencyDropDown.Value = CurrencyString(ind);
            end
            
            
            if TEST(4) == 0 & value == 'GBP'
               app.CurrencySetError = CurrencySetError(app);
               ind = find(TEST,1);
               app.BaseCurrencyDropDown.Value = CurrencyString(ind);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PortfolioSelectionTab = uitab(app.TabGroup);
            app.PortfolioSelectionTab.Title = 'Portfolio Selection';
            app.AddOptiontoPortfolioPanel = uipanel(app.PortfolioSelectionTab);
            app.AddOptiontoPortfolioPanel.Title = 'Add Option to Portfolio';
            app.SetDateandRatesButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.SetDateandRatesButton.ButtonPushedFcn = createCallbackFcn(app, @SetDateandRatesButtonPushed, true);
            app.SetDateandRatesButton.Text = 'Set Date and Rates';
            app.AddtoPortfolioButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.AddtoPortfolioButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoPortfolioButtonPushed, true);
            app.AddtoPortfolioButton.Text = 'Add to Portfolio';
            app.DeletePortfolioButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.DeletePortfolioButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePortfolioButtonPushed, true);
            app.DeletePortfolioButton.Text = 'Delete Portfolio';
            app.OptionStyleButtonGroup = uibuttongroup(app.AddOptiontoPortfolioPanel);
            app.OptionStyleButtonGroup.Title = 'Option Style';
            app.EuropeanButton = uiradiobutton(app.OptionStyleButtonGroup);
            app.EuropeanButton.Text = 'European';
            app.EuropeanButton.Value = true;
            app.AmericanButton = uiradiobutton(app.OptionStyleButtonGroup);
            app.AmericanButton.Text = 'American';
            app.PositionButtonGroup = uibuttongroup(app.AddOptiontoPortfolioPanel);
            app.PositionButtonGroup.Title = 'Position';
            app.LongButton = uiradiobutton(app.PositionButtonGroup);
            app.LongButton.Text = 'Long';
            app.LongButton.Value = true;
            app.ShortButton = uiradiobutton(app.PositionButtonGroup);
            app.ShortButton.Text = {'Short'; ''};
            app.OptionTypeButtonGroup = uibuttongroup(app.AddOptiontoPortfolioPanel);
            app.OptionTypeButtonGroup.Title = 'Option Type';
            app.CallButton = uiradiobutton(app.OptionTypeButtonGroup);
            app.CallButton.Text = 'Call';
            app.CallButton.Value = true;
            app.PutButton = uiradiobutton(app.OptionTypeButtonGroup);
            app.PutButton.Text = 'Put';
            app.InstrumentTypeDropDown = uidropdown(app.AddOptiontoPortfolioPanel);
            app.InstrumentTypeDropDown.Items = {'Vanilla', 'Binary', 'Barrier', 'Lookback'};
            app.InstrumentTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @InstrumentTypeDropDownValueChanged, true);
            app.InstrumentTypeDropDown.Value = 'Vanilla';
            app.InstrumentTypeDropDownLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.InstrumentTypeDropDownLabel.Text = 'Instrument Type';
            app.StrikeEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.StrikeEditField.Limits = [0 Inf];
            app.StrikeEditField.Value = 90;
            app.StrikeEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.StrikeEditFieldLabel.Text = 'Strike';
            app.UnderlyingPriceEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.UnderlyingPriceEditFieldLabel.Text = 'Underlying Price';
            app.UnderlyingPriceEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.UnderlyingPriceEditField.Value = 90;
            app.VolatilityEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.VolatilityEditFieldLabel.Text = 'Volatility';
            app.VolatilityEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.VolatilityEditField.Value = 0.12;
            app.OptionMaturityDatePickerLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.OptionMaturityDatePickerLabel.Text = 'Option Maturity';
            app.OptionMaturityDatePicker = uidatepicker(app.AddOptiontoPortfolioPanel);
            app.OptionMaturityDatePicker.Value = datetime([2021 3 3]);
            app.YieldEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.YieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.YieldLabel.Text = 'Yield (%)';
            app.BarrierPanel = uipanel(app.AddOptiontoPortfolioPanel);
            app.BarrierPanel.Title = 'Barrier Panel';
            app.GridLayout = uigridlayout(app.BarrierPanel);
            app.BarrierTypeDropDown = uidropdown(app.GridLayout);
            app.BarrierTypeDropDown.Items = {'Up Knock-In', 'Up Knock-Out', 'Down Knock-In', 'Down Knock-Out'};
            app.BarrierTypeDropDown.ItemsData = {'UI', 'UO', 'DI', 'DO'};
            app.BarrierTypeDropDown.Value = 'UI';
            app.BarrierTypeDropDownLabel = uilabel(app.GridLayout);
            app.BarrierTypeDropDownLabel.Text = 'Barrier Type';
            app.BarrierValueEditFieldLabel = uilabel(app.GridLayout);
            app.BarrierValueEditFieldLabel.Text = 'Barrier Value';
            app.BarrierValueEditField = uieditfield(app.GridLayout, 'numeric');
            app.BarrierValueEditField.Limits = [0 Inf];
            app.BinaryPanel = uipanel(app.AddOptiontoPortfolioPanel);
            app.BinaryPanel.Title = 'Binary Panel';
            app.GridLayout2 = uigridlayout(app.BinaryPanel);
            app.BinaryValueEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BinaryValueEditField.Limits = [0 Inf];
            app.BinaryValueEditFieldLabel = uilabel(app.GridLayout2);
            app.BinaryValueEditFieldLabel.Text = 'Binary Value';
            app.SimulationTrialsEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.SimulationTrialsEditFieldLabel.Text = 'Simulation Trials';
            app.SimulationTrialsEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.SimulationTrialsEditField.Value = 1000;
            app.QuantitySpinner = uispinner(app.AddOptiontoPortfolioPanel);
            app.QuantitySpinner.Limits = [0 Inf];
            app.QuantitySpinner.Value = 1;
            app.QuantitySpinnerLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.QuantitySpinnerLabel.Text = 'Quantity';
            app.BaseCurrencyDropDownLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.BaseCurrencyDropDownLabel.Text = 'Base Currency';
            app.BaseCurrencyDropDown = uidropdown(app.AddOptiontoPortfolioPanel);
            app.BaseCurrencyDropDown.Items = {'EUR', 'USD', 'AUD', 'GBP'};
            app.BaseCurrencyDropDown.ValueChangedFcn = createCallbackFcn(app, @BaseCurrencyDropDownValueChanged, true);
            app.BaseCurrencyDropDown.Value = 'EUR';
            app.PortfolioCompositionPanel = uipanel(app.PortfolioSelectionTab);
            app.PortfolioCompositionPanel.Title = 'Portfolio Composition';
            app.UIAxes5 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes2 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UITable3 = uitable(app.PortfolioSelectionTab);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.UITable3.ColumnSortable = [true true true true true true true true true true true true];
            app.UITable4 = uitable(app.PortfolioSelectionTab);
            app.UITable4.ColumnName = '';
            app.UITable4.RowName = {};
            app.UITable2 = uitable(app.PortfolioSelectionTab);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = [true true true true true true true true true true true];
            app.LONGLabel = uilabel(app.PortfolioSelectionTab);
            app.LONGLabel.Text = 'LONG';
            app.SHORTLabel = uilabel(app.PortfolioSelectionTab);
            app.SHORTLabel.Text = 'SHORT';
            app.DataandPortfolioTab = uitab(app.TabGroup);
            app.DataandPortfolioTab.Title = 'Data and Portfolio';
            app.Panel = uipanel(app.DataandPortfolioTab);
            app.Panel.Title = 'Panel';
            app.UIAxes6 = uiaxes(app.Panel);
            xlabel(app.UIAxes6, 'Date')
            ylabel(app.UIAxes6, 'Exchange Rate')
            zlabel(app.UIAxes6, 'Z')
            app.UITable5 = uitable(app.DataandPortfolioTab);
            app.UITable5.ColumnName = '';
            app.UITable5.RowName = {};
            app.Panel_2 = uipanel(app.DataandPortfolioTab);
            app.CheckBox = uicheckbox(app.Panel_2);
            app.CheckBox2 = uicheckbox(app.Panel_2);
            app.CheckBox2.Text = 'Check Box2';
            app.CheckBox3 = uicheckbox(app.Panel_2);
            app.CheckBox3.Text = 'Check Box3';
            app.CheckBox4 = uicheckbox(app.Panel_2);
            app.CheckBox4.Text = 'Check Box4';
            app.CheckBox5 = uicheckbox(app.Panel_2);
            app.CheckBox5.Text = 'Check Box5';
            app.CheckBox6 = uicheckbox(app.Panel_2);
            app.CheckBox6.Text = 'Check Box6';
            app.CheckBox7 = uicheckbox(app.Panel_2);
            app.CheckBox7.Text = 'Check Box7';
            app.StartDateDatePickerLabel = uilabel(app.Panel_2);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.Panel_2);
            app.StartDateDatePicker.Value = datetime([1995 3 2]);
            app.EndDateDatePickerLabel = uilabel(app.Panel_2);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.Panel_2);
            app.EndDateDatePicker.Value = datetime([2021 3 4]);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRMIA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end